classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        BATUHANBEELLabel               matlab.ui.control.Label
        SortAlgorithmsButton           matlab.ui.control.Button
        SortAlgorithmsTimeComparisonButton  matlab.ui.control.Button
        BinarySearchButton             matlab.ui.control.Button
        FibonacciButton                matlab.ui.control.Button
        MatrixProductButton            matlab.ui.control.Button
        ContactButton                  matlab.ui.control.Button
        IkcuLogoImage_2                matlab.ui.control.Image
        RandomizedSelectButton         matlab.ui.control.Button
        SortAlgorithmsTab              matlab.ui.container.Tab
        INSERTIONSORTButton            matlab.ui.control.Button
        BUBBLESORTButton               matlab.ui.control.Button
        MERGESORTButton                matlab.ui.control.Button
        QUICKSORTButton                matlab.ui.control.Button
        HEAPSORTButton                 matlab.ui.control.Button
        RADIXSORTButton                matlab.ui.control.Button
        COUNTINGSORTButton             matlab.ui.control.Button
        BUCKETSORTButton               matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        lower_bound                    matlab.ui.control.NumericEditField
        RANDOMARRAYButton              matlab.ui.control.Button
        LOWERBOUNDLabel                matlab.ui.control.Label
        UPPERBOUNDLabel                matlab.ui.control.Label
        upper_bound                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.EditField
        CREATEARRAYButton              matlab.ui.control.Button
        PleasecreateanarrayonlyintegerLabel  matlab.ui.control.Label
        CLEARButton                    matlab.ui.control.Button
        FinishSortingButton            matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        array_size                     matlab.ui.control.NumericEditField
        ARRAYSIZELabel                 matlab.ui.control.Label
        SortingSPEEDSliderLabel        matlab.ui.control.Label
        SPEEDSlider                    matlab.ui.control.Slider
        RETURNMAINPAGEButton_7         matlab.ui.control.Button
        SeedCheckBox                   matlab.ui.control.CheckBox
        HelpAboutSortingAlgorithmsButton  matlab.ui.control.Button
        SortAlgorithmsTimeComparisonTab  matlab.ui.container.Tab
        RETURNMAINPAGEButton_6         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        BubbleSortCheckBox             matlab.ui.control.CheckBox
        InsertionSortCheckBox          matlab.ui.control.CheckBox
        MergeSortCheckBox              matlab.ui.control.CheckBox
        HeapSortCheckBox               matlab.ui.control.CheckBox
        QuickSortCheckBox              matlab.ui.control.CheckBox
        BucketSortCheckBox             matlab.ui.control.CheckBox
        CountingSortCheckBox           matlab.ui.control.CheckBox
        RadixSortCheckBox              matlab.ui.control.CheckBox
        ComparisonAllButton            matlab.ui.control.Button
        ComparisonChoosenAlgorithmsButton  matlab.ui.control.Button
        ClearButton_3                  matlab.ui.control.Button
        RangeEditField                 matlab.ui.control.NumericEditField
        ArraySizeEditField             matlab.ui.control.NumericEditField
        MaxRangeEditFieldLabel         matlab.ui.control.Label
        ArraySzieEditFieldLabel        matlab.ui.control.Label
        ArrayNumberEditFieldLabel      matlab.ui.control.Label
        ArrayNumberEditField           matlab.ui.control.NumericEditField
        BinarySearchTab                matlab.ui.container.Tab
        CreateRandomArrayButton        matlab.ui.control.Button
        BINARYSEARCHLabel_2            matlab.ui.control.Label
        Lower_Bound                    matlab.ui.control.NumericEditField
        Upper_Bound                    matlab.ui.control.NumericEditField
        Array_size                     matlab.ui.control.NumericEditField
        ArraySizeLabel                 matlab.ui.control.Label
        UpperBoundLabel                matlab.ui.control.Label
        LowerBoundLabel                matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        UNSORTEDARRAYSTextArea         matlab.ui.control.TextArea
        SORTEDARRAYSTextArea           matlab.ui.control.TextArea
        FindNumberIndexButton          matlab.ui.control.Button
        KEYEditFieldLabel              matlab.ui.control.Label
        KEYEditField                   matlab.ui.control.EditField
        NthIndexEditFieldLabel         matlab.ui.control.Label
        NthIndexEditField              matlab.ui.control.EditField
        RETURNMAINPAGEButton_5         matlab.ui.control.Button
        AboutBinarySearchButton        matlab.ui.control.Button
        FibonacciTab                   matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        FindNumberButton               matlab.ui.control.Button
        ClearGraphButton               matlab.ui.control.Button
        nthFibonacciNumberEditFieldLabel  matlab.ui.control.Label
        FibonacciValueEditFieldLabel   matlab.ui.control.Label
        nthFibonacciValueEditField     matlab.ui.control.NumericEditField
        fibonacci_answer               matlab.ui.control.EditField
        RETURNMAINPAGEButton_4         matlab.ui.control.Button
        AboutFibonacciButton           matlab.ui.control.Button
        MatrixProductTab               matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        ClearButton                    matlab.ui.control.Button
        FIRSTMATRIXLabel               matlab.ui.control.Label
        SECONDMATRIXLabel              matlab.ui.control.Label
        CreateRandomMatrixButton       matlab.ui.control.Button
        RESULTMATRIXLabel              matlab.ui.control.Label
        MatrixProductLabel             matlab.ui.control.Label
        CreateMatrixManuallyButton     matlab.ui.control.Button
        FirstMatrixRowLabel            matlab.ui.control.Label
        row                            matlab.ui.control.NumericEditField
        FirstMatrixColumnLabel         matlab.ui.control.Label
        col1androw2                    matlab.ui.control.NumericEditField
        SecondMatrixRowEditFieldLabel  matlab.ui.control.Label
        SecondMatrixRowEditField       matlab.ui.control.NumericEditField
        SecondMatrixColumnLabel        matlab.ui.control.Label
        col2                           matlab.ui.control.NumericEditField
        RETURNMAINPAGEButton_2         matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        IkcuLogoImage_4                matlab.ui.control.Image
        ContactTab                     matlab.ui.container.Tab
        GmailImage                     matlab.ui.control.Image
        RETURNMAINPAGEButton_3         matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        IkcuLogoImage_3                matlab.ui.control.Image
        BatuhanBEELsInformationLabel   matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        zmirKatipelebiWebLabel         matlab.ui.control.Label
        Image3_2                       matlab.ui.control.Image
        MailTab                        matlab.ui.container.Tab
        RETURNMAINPAGEButton           matlab.ui.control.Button
        SendMailButton                 matlab.ui.control.Button
        SUBJECTEditFieldLabel          matlab.ui.control.Label
        SUBJECTEditField               matlab.ui.control.EditField
        BODYTextAreaLabel              matlab.ui.control.Label
        BODYTextArea                   matlab.ui.control.TextArea
        MailsentsuccessfullyLabel      matlab.ui.control.Label
        IkcuLogoImage                  matlab.ui.control.Image
        RandomizedSelectTab            matlab.ui.container.Tab
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        TextArea_4                     matlab.ui.control.TextArea
        RandomArrayButton              matlab.ui.control.Button
        ithsmallestButton              matlab.ui.control.Button
        TextArea_5                     matlab.ui.control.TextArea
        upper_bound_6                  matlab.ui.control.NumericEditField
        lower_bound_7                  matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        ClearButton_2                  matlab.ui.control.Button
        UNSORTEDARRAYLabel             matlab.ui.control.Label
        INDEXLabel                     matlab.ui.control.Label
        NUMBERLabel                    matlab.ui.control.Label
        RANDOMIZEDSELECTLabel          matlab.ui.control.Label
        SORTEDARRAYLabel               matlab.ui.control.Label
        array_size_16                  matlab.ui.control.NumericEditField
        ARRAYSIZELabel_2               matlab.ui.control.Label
        UPPERBOUNDLabel_2              matlab.ui.control.Label
        LOWERBOUNDLabel_2              matlab.ui.control.Label
        IdonotwantseesamenumbersCheckBox  matlab.ui.control.CheckBox
        AboutRandomizedSelectButton    matlab.ui.control.Button
        TextArea_6                     matlab.ui.control.TextArea
        RETURNMAINPAGEButton_8         matlab.ui.control.Button
        IkcuLogoImage_5                matlab.ui.control.Image
    end

    
    properties (Access = private)
        changingValue_arraysize;
        changingValue_arrayrange;
        unsorted_array;
        changingValueRange;
        changingValueSize;
        changingValue
        CallingApp
        BUTON 
        arrayRange;
        arrayNumber; % Description
        insertion=[]
        bubble=[]
        merge=[]
        bucket=[]
        heap=[]
        radix=[]
        counting=[]
        quick=[]
        time_of_insertion = zeros();
        time_of_bubble = zeros();
        time_of_merge = zeros();
        time_of_bucket = zeros();
        time_of_radix = zeros();
        time_of_counting = zeros();
        time_of_quick = zeros();
        time_of_heap = zeros(); % Description
    end
    
    %%Batuhan Beel 180403031 22.11.2021
    
    methods (Access = private)
        
         % BINARY SEARCH %
         function results = binarySearch(app,array,number,index)
            midpoint = ceil(length(array)/2); %%divide the array in half
            if array(midpoint) == number %%If the number we are looking for is in the middle, print it.
                results = midpoint + index;
            elseif array(midpoint) < number %%if the number we are looking for is greater in the midpoint:
                list = array(midpoint+1:length(array)); %%look to the right
                results = binarySearch(app,list,number,midpoint+index);
            else %%if the number we are looking for is small in the midpoint:
                list = array(1:midpoint-1); %%look to the left
                results = binarySearch(app,list,number,index);
            end
        end   


        % INSERTION SORT %
        function array = Insertion_sort(app,array) %%insertion sort algorithm function
            x = (1:length(array)); %%x-axis 
            for j = 2:length(array) %%array length
                key = array(j); %%set a key value.
                i = j - 1; %%run through the list of items.
                while i >0 && array(i)> key %%find the correct position.compare the smaller and greater of the numbers. 
                    array(i+1) = array(i); %%Do this only if key is smaller than other values, shift the value one position to the left
                    i = i - 1; %%reposition from right to left.
                    bar(app.UIAxes2,x,array,"black"); %%create bar chart.
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x(j),array(j),"blue") 
                    bar(app.UIAxes2,x(i+1),array(i+1),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation

                end
                array(i+1) = key;  %%when finish shifting the number items, our key is sorted correctly.
                
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Insertion Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom','LineWidth',2)
            end
            
        end


        %%%%%% MERGE SORT %%%%%%%%%%
        function unsorted_array = Merge_Sort(app,unsorted_array,l,r) %%merge two subarrays of arr[]. 
            if(l<r)
                mid = floor((l+r)/2); %%Finding the midpoint of the array
                unsorted_array = Merge_Sort(app,unsorted_array,l,mid);
                unsorted_array = Merge_Sort(app,unsorted_array,mid+1,r);
                unsorted_array = Merge(app,unsorted_array,l,mid,r);
            end 
        end
        
        function unsorted_array = Merge(app,unsorted_array, l, mid, r)
            x = (1:length(unsorted_array));
            n1 = mid - l + 1;%%first half of array
            n2 = r - mid; %%second half of array.
            Left = zeros(1, n1+1); %left side of list.
            Right = zeros(1, n2+1); %right side of list.
            %%copy data to temp arrays left_side and right_side
            for i=1:n1
                Left(i) = unsorted_array(l+ i - 1);    
            end
            for j=1:n2
                Right(j) = unsorted_array(mid+j);
            end
            Left(n1+1) = inf; 
            Right(n2+1) = inf;
            i=1;
            j=1;
            
            for k=l:r

                if Left(i)<=Right(j)  %%checking if any element was right
                    unsorted_array(k)=Left(i); 
                    i=i+1;
                    
                else %%checking if any element was left
                    unsorted_array(k)=Right(j);
                    j = j+1;
                end
                bar(app.UIAxes2,x,unsorted_array,"black"); %%create bar chart
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(k),unsorted_array(k),"magenta");
                hold(app.UIAxes2,"off");
                pause((10-floor(app.SPEEDSlider.Value))*0.01);  %%speed button for bar animation
            end
            bar(app.UIAxes2,unsorted_array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Merge Sort');
            for i=1:numel(unsorted_array)
                      text(app.UIAxes2,(i),unsorted_array(i),num2str(unsorted_array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
        
        %% Smallest Value
        function [ith_smallest] = qselect(app,array, k)
        low = 1;
        high = length(array);
        
        while(true)
            median = fix((low+high)/2);
            if (array(median) < array(high))
                temp = array(median) ;
                array(median) = array(high);
                array(high) = temp ;
            end
             if (array(median) < array(low))
                temp = array(median) ;
                array(median) = array(low);
                array(low) = temp ;
             end
             if (array(high) < array(low))
                temp = array(high) ;
                array(high) = array(low);
                array(low) = temp ;
             end
             index = array (high) ;
             i = low;
             for j = low:high-1
                 if(array(j)<= index)
                     temp = array(i);
                     array(i) = array(j);
                     array(j) = temp;
                     i = i+1;
                 end
             end
              temp = array(high); 
              array(high)= array(i); 
              array(i)=temp; 
              median=i-low+1;
            if(k<median)
              high =i-1;
            elseif(k>median)
              low =i+1; 
              k=k-median;
            else
              ith_smallest = array(i);
              return;
            end
  
        end
        end
        
        % QUICK SORT %
        function array = quick_sort(app,array,r,p)
            if r < p
                [array,q] = partition(app,array,r,p); %%call partition function
                array = quick_sort(app,array,r,q-1); %%call quick_sort function
                array = quick_sort(app,array,q+1,p); %%call quick_sort function
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Quick Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom','LineWidth',2)
            end
        end
        
        function [array,q] = partition(app,array,r,p)
            x=(1:length(array));
            i = (r-1); %%smaller elements index
            q = array(p); %%pivot
            for j=r:p %% smal index to high index
                if array(j) < q %% elements is smallere than or equal to pivot;
                    i = i+1; %% increasing smaller index 
                    temp1 = array(j); 
                    temp2 = array(i);
                    array(j) = temp2;
                    array(i) = temp1;
                    bar(app.UIAxes2,x,array,"black");
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x(j+1),array(j+1),"blue")
                    bar(app.UIAxes2,x(j),array(j),"cyan")
                    bar(app.UIAxes2,x(i),array(i),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01);
                    
                end
            end
            temp3 = array(i+1) ;
            temp4 = array(p);
            array(i+1) = temp4;
            array(p) = temp3;
            q = i+1; 
        end


        % HEAP SORT %
        function array = heap_sort(app,array)
            x = (1:length(array));
            n = length(array); %%size
            array = max_heap_sort(app,array,n); %%call max_heap_sort func.
            
            for i = n:-1:2 %%one by one extract elements 
                key = array(i); %%swap
                array(i) = array(1);
                array(1) = key;
                n = n - 1;
                array = heapify(app,array,n,1);
                bar(app.UIAxes2,x,array,"black"); %%create bar chart
                hold(app.UIAxes2,"on")
                bar(app.UIAxes2,x(i),array(i),"cyan")
                hold(app.UIAxes2,"off")
                pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation  
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Heap Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
        
        function array = max_heap_sort(app,array,n) %%build max heap
            for i=floor(n/2):-1:1 %%last parent will be at ((n//2)-1) we can start at that location. 
                array = heapify(app,array,n,i); %%call heapify func.
            end
        end

        function array = heapify(app,array,n,i)
             x = (1:length(array));
             l = i*2; %%left = 2*i 
             r = (i*2)+1; %%right = 2*i +1
             hold(app.UIAxes2,"on") %%create bar chart
             bar(app.UIAxes2,x(i),array(i),"magenta")
             hold(app.UIAxes2,"off")
             if l <= n && array(l)>array(i) %%see if left child of root exists and is greater than root 
                 largest = l; %%make largest equal to 1
             else 
                 largest = i; %%make largest equal to i
             end
             if r <= n && array(r) > array(largest) %%see if right child of root exists and is greater than root  
                 largest = r;
             end
             if largest ~= i %%if largest is not equal i
                 key = array(i); %%swap 
                 array(i) = array(largest);
                 array(largest) = key;
                 array = heapify(app,array,n,largest); %%call heapify func.
             end
        end


        % BUBBLE SORT %
        function array = bubbleSort(app,array) %%bubble sort algorithm function
            x = (1:length(array));
            bub = size (array,2); %%array length
            for i = 1:(bub+1) %%look at each items in the series one by one and check up how sort  
                for j = bub:-1:(i+1)
                    if array(j)<array(j-1)  %%compare the magnitudes of the values.
                        result = array(j); %%If the item is greater than its adjacent value, then swap 
                        array(j) = array(j-1);
                        array(j-1) = result;
                        bar(app.UIAxes2,x,array,"black"); %%create bar chart
                        hold(app.UIAxes2,"on")
                        bar(app.UIAxes2,x(j),array(j),"magenta")
                        bar(app.UIAxes2,x(j-1),array(j-1),"cyan")
                        hold(app.UIAxes2,"off")
                        pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation  
                    end
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bubble Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end


        % BUCKET SORT %
        function array = bucket_sort(app,array)
            x_axis = (1:length(array));
            max_array_value = max(array); %%max element for array
            bucket_list = zeros(1,(max_array_value+1)); %%new empty output array
            for j = 1:numel(array) 
                bucket_list(array(j)) = (bucket_list(array(j)) + 1); 
            end
            x = 1;
            for i = 1:max_array_value+1
                for j = 1: bucket_list(i)
                    array(x) = i;
                    x = x + 1;
                    bar(app.UIAxes2,x_axis,array,"black"); %%create bar chart
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,x_axis(x-1),array(x-1),"cyan")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                end
            end
            bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Bucket Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
       
        
       % COUNTING SORT %
        function array = counting_sort(app,array)
             x = (1:length(array));
             max1 = max(array); %%max element for array
             min1 = min(array); %%min element for array
             count = zeros((max1-min1+1),1); %%new empty list for count.
             for num = array
                 count(num-min1+1) = count(num-min1+1) +1; %%add 1 to count.
             end
             for i=1:length(array)
                bar(app.UIAxes2,x,array,"black"); %%create bar chart
                hold(app.UIAxes2,"on");
                bar(app.UIAxes2,x(i),array(i),"cyan");
                hold(app.UIAxes2,"off");
                pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                
            end
            j = 1;
            for i = (min1:max1)     
                while( count(i-min1 +1) > 0)
                    array(j) = i;
                    j = j+1;
                    bar(app.UIAxes2,x,array,"black"); %%create bar chart
                    hold(app.UIAxes2,"on");
                    bar(app.UIAxes2,x(j-1),array(j-1),"magenta");
                    hold(app.UIAxes2,"off");
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                    count(i - min1 + 1) = count(i - min1 + 1) - 1; 
                end
                bar(app.UIAxes2,array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                title(app.UIAxes2,'Counting Sort');
                for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
                end
            end
        end
         
         
         % RADIX SORT %
        function array = radix_sort(app,array)
            max1 = max(array); %%max element for array
            exp = 1; 
            while max1/exp >= 1
                place = exp; %%do counting sort for every digit. Note that instead of passing digit number, exp is passed. exp is 10^i  where i is current digit number
                count = zeros(1,11); %%new empty array for count
                out = zeros(1,numel(array)); %%new empty array for radix 
                for j = 1:numel(array) 
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10) +1) + 1; %%store count of occurrences in count[] 
                end
                
               
                for i = 2:11 
                    count(i) = count(i) + count(i-1); %%change count[i] so that count[i] now contains actual position of this digit in output array  
                end
           
                for j = numel(array):-1:1 
                    out(count(mod(floor(array(j)/place),10)+1)) = array(j);
                    count(mod(floor(array(j)/place),10)+1) = count(mod(floor(array(j)/place),10)+1) - 1;
                    bar(app.UIAxes2,out,"black"); %%create bar chart
                    hold(app.UIAxes2,"on")
                    bar(app.UIAxes2,count(mod(floor(array(j)/place),10) +1)+1,out(count(mod(floor(array(j)/place),10)+1)+1),"magenta")
                    hold(app.UIAxes2,"off")
                    pause((10-floor(app.SPEEDSlider.Value))*0.01); %%speed button for bar animation
                 
                end
                array = out;
                exp = exp * 10;
            end
            bar(app.UIAxes2,out,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
            title(app.UIAxes2,'Radix Sort');
            for i=1:numel(array)
                      text(app.UIAxes2,(i),array(i),num2str(array(i),'%0.0f'),...
                   'HorizontalAlignment','center',...
                   'VerticalAlignment','bottom')
            end
        end
       
   
        function results = voice(app,String)
            caUserInput = {String};
            caUserInput = char(caUserInput);
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            Speak(obj, caUserInput)
            
        end
        function unsorted_array = Insertion(app,unsorted_array) %%insertion sort algorithm function
            length = size(unsorted_array,2); %%array length
            for i = 2:length 
                key = unsorted_array(i); %%set a key value.
                j = i - 1; %%run through the list of items.
                
                while j >0 && unsorted_array(j)> key %%find the correct position.compare the smaller and greater of the numbers. 
                    unsorted_array(j+1) = unsorted_array(j); %%Do this only if key is smaller than other values, shift the value one position to the left
                    j = j - 1; %%reposition from right to left.
                end
                unsorted_array(j+1) = key; %%when finish shifting the number items, our key is sorted correctly.
            end 
        end
       
       function Time_Comparison(app,currentvalue,range,number_of_array,iteration)
            rng('default');
            rng(1721);
            range_of_array = range; %define range
            number_of_runs = number_of_array; %define number of elements
            iteration_number = iteration;
            cla(app.UIAxes);
            list_of_legend=[];
            app.insertion=[];
            app.bubble=[];
            app.merge=[];
            app.bucket=[];
            app.heap=[];
            app.radix=[];
            app.counting=[];
            app.quick=[];

            for j = number_of_runs:10:iteration_number
                for i=1:30
                    randomarray = randperm(range_of_array,j);   %create random array
                    max_random_array = max(randomarray);        %find the max value

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Insertion Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(1)
                        tic;
                        insertion_sort(randomarray);
                        insertion_sort_time = toc;
                        app.time_of_insertion(i,j) = insertion_sort_time;
                        sum_of_insertion_sort = sum(app.time_of_insertion);
                        averageof_insertionsort = sum_of_insertion_sort / 30 ;
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Bubble Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(2)
                        tic;
                        bubble_sort(randomarray);
                        bubble_sort_time = toc;
                        app.time_of_bubble(i,j) = bubble_sort_time;
                        sum_of_bubble_sort = sum(app.time_of_bubble);
                        averageof_bubblesort = sum_of_bubble_sort / 30 ;
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Merge Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(3)
                        tic;
                        merge_sorti(randomarray);
                        merge_sort_time = toc;
                        app.time_of_merge(i,j) = merge_sort_time;
                        sum_of_merge_sort = sum(app.time_of_merge);
                        averageof_mergesort = sum_of_merge_sort / 30;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Bucket Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(4)
                        tic;
                        bucket_sort(randomarray);
                        bucket_sort_time = toc;
                        app.time_of_merge(i,j) = bucket_sort_time;
                        sum_of_bucket_sort = sum(app.time_of_merge);
                        averageof_bucketsort = sum_of_bucket_sort / 30 ;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Radix Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(5)
                        tic;
                        radix_sort(randomarray);
                        radix_sort_time = toc;
                        app.time_of_radix(i,j) = radix_sort_time;
                        sum_of_radix_sort = sum(app.time_of_radix);
                        averageof_radixsort = sum_of_radix_sort / 30 ;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Counting Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(6)
                        tic;
                        counting_sort(randomarray,max_random_array);
                        counting_sort_time = toc;
                        app.time_of_counting(i,j) = counting_sort_time;
                        sum_of_counting_sort = sum(app.time_of_counting);
                        averageof_countingsort = sum_of_counting_sort / 30;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Quick Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(7)
                        tic;
                        quicksorti(randomarray,1,length(randomarray));
                        quick_sort_time = toc;
                        app.time_of_quick(i,j) = quick_sort_time;
                        sum_of_quick_sort = sum(app.time_of_quick);
                        averageof_quicksort = sum_of_quick_sort / 30;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Elapsed time by Heap Sort%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if currentvalue(8)
                        tic;
                        heap_sort(randomarray);
                        heap_sort_time = toc;
                        app.time_of_heap(i,j) = heap_sort_time;
                        sum_of_heap_sort = sum(app.time_of_heap);
                        averageof_heapsort = sum_of_heap_sort / 30;
                    end
                end

                if currentvalue(1)
                    app.insertion=[app.insertion averageof_insertionsort(1,j)];
                end
                if currentvalue(2)
                    app.bubble=[app.bubble averageof_bubblesort(1,j)];
                end
                if currentvalue(3)
                    app.merge=[app.merge averageof_mergesort(1,j)];
                end
                if currentvalue(4)
                    app.bucket=[app.bucket averageof_bucketsort(1,j)];
                end
                if currentvalue(5)
                    app.radix=[app.radix averageof_radixsort(1,j)];
                end
                if currentvalue(6)
                    app.counting=[app.counting averageof_countingsort(1,j)];
                end
                if currentvalue(7)
                    app.quick=[app.quick averageof_quicksort(1,j)];
                end
                if currentvalue(8)
                    app.heap=[app.heap averageof_heapsort(1,j)];
                end

            end

            k=number_of_runs:10:iteration_number;
            if currentvalue(1)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.insertion,'r')
                list_of_legend=[list_of_legend "Insertion"];
            end

            if currentvalue(2)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.bubble,"b");
                list_of_legend=[list_of_legend "Bubble"];
            end

            if currentvalue(3)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.merge,"k");
                list_of_legend=[list_of_legend "Merge"];
            end
            if currentvalue(4)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.bucket,"m");
                list_of_legend=[list_of_legend "Bucket"];
            end
            if currentvalue(5)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.radix,"r");
                list_of_legend=[list_of_legend "Radix"];
            end
            if currentvalue(6)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.counting,"g");
                list_of_legend=[list_of_legend "Counting"];
            end
            if currentvalue(7)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.quick,"c");
                list_of_legend=[list_of_legend "Quick"];
            end

            if currentvalue(8)
                hold(app.UIAxes,"on");
                plot(app.UIAxes,k,app.heap,"b");
                list_of_legend=[list_of_legend "Heap"];
            end
            legend(app.UIAxes,list_of_legend);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SortAlgorithmsButton
        function SortAlgorithmsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
        end

        % Button pushed function: SortAlgorithmsTimeComparisonButton
        function SortAlgorithmsTimeComparisonButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
        end

        % Button pushed function: BinarySearchButton
        function BinarySearchButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(4);
        end

        % Button pushed function: FibonacciButton
        function FibonacciButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(5);
        end

        % Button pushed function: MatrixProductButton
        function MatrixProductButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(6);
        end

        % Button pushed function: ContactButton
        function ContactButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(7);
        end

        % Button pushed function: SendMailButton
        function SendMailButtonPushed(app, event)
            setpref('Internet','SMTP_Server','smtp.gmail.com');
            setpref('Internet','E_mail','mermercalismasi2020@gmail.com');

            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.starttls.enable','true');
            props.setProperty('mail.smtp.port','587');
            
            setpref('Internet','SMTP_Username','mermercalismasi2020@gmail.com');
            setpref('Internet','SMTP_Password','mermer2020calismasi');
            
            sendmail('batuhanbeel45@gmail.com',app.SUBJECTEditField.Value, app.BODYTextArea.Value);
            
            set(app.MailsentsuccessfullyLabel,'visible','on')
            app.SUBJECTEditField.Value = '';
            app.BODYTextArea.Value = '';
        end

        % Image clicked function: IkcuLogoImage, IkcuLogoImage_2, 
        % IkcuLogoImage_3, IkcuLogoImage_4, IkcuLogoImage_5
        function IkcuLogoImageClicked(app, event)
            web('https://ikcu.edu.tr/','-new')
        end

        % Image clicked function: GmailImage
        function GmailImageClicked(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(8);
            web('mailto:mermercalismasi2020@gmail.com')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data(:) = [];
            app.UITable2.Data(:) = [];
            app.UITable3.Data(:) = [];
            app.row.Value = 1;
            app.col1androw2.Value = 1;
            app.col2.Value = 1;
        end

        % Button pushed function: CreateRandomMatrixButton
        function CreateRandomMatrixButtonPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            row = app.row.Value;
            col = app.col1androw2.Value;
            col2 = app.col2.Value;
            row2 = app.SecondMatrixRowEditField.Value;
            matrix2 = round(-100+1000*rand((row2),(col2))); %%create random matrix1
            matrix1 = round(-100+1000*rand((row),(col))); %%create random matrix2
            app.UITable.Data = matrix1;
            app.UITable2.Data = matrix2;
            get(app.UITable,"Data");
            get(app.UITable2,"Data");
            app.UITable3.Data(:) = [];
        end

        % Button pushed function: CreateMatrixManuallyButton
        function CreateMatrixManuallyButtonPushed(app, event)
             try
                global row
                global col
                global col2
                global matrix1
                global matrix2
                
                row = app.row.Value;
                col = app.col1androw2.Value;
                col2 = app.col2.Value;
                matrix1 = zeros(row,col);
                matrix2 = zeros(col,col2);
                app.UITable.Data = matrix1;
                app.UITable2.Data = matrix2;
                get(app.UITable,"Data");
                get(app.UITable2,"Data");
                app.UITable3.Data(:) = [];
            catch
                msgbox('Please check your matrix','Error','error')
            end
        end

        % Button pushed function: INSERTIONSORTButton
        function INSERTIONSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                Insertion_sort(app,app.unsorted_array)
                %app.TextArea_5.Value = num2str(app.unsorted_array);
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: MERGESORTButton
        function MERGESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                Merge_Sort(app,app.unsorted_array,1,length(app.unsorted_array))
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: BUBBLESORTButton
        function BUBBLESORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                bubbleSort(app,app.unsorted_array)
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
            
        end

        % Button pushed function: BUCKETSORTButton
        function BUCKETSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                bucket_sort(app,app.unsorted_array)
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: QUICKSORTButton
        function QUICKSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                quick_sort(app,app.unsorted_array,1,length(app.unsorted_array))
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: COUNTINGSORTButton
        function COUNTINGSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                counting_sort(app,app.unsorted_array)
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: HEAPSORTButton
        function HEAPSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                heap_sort(app,app.unsorted_array)
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
        end

        % Button pushed function: RADIXSORTButton
        function RADIXSORTButtonPushed(app, event)
            if isempty(app.unsorted_array)
                msgbox('Please set the array..','Error','error')
            else
                set(app.COUNTINGSORTButton,'Enable','off');
                set(app.BUCKETSORTButton,'Enable','off');
                set(app.RADIXSORTButton,'Enable','off');
                set(app.HEAPSORTButton,'Enable','off');
                set(app.QUICKSORTButton,'Enable','off');
                set(app.MERGESORTButton,'Enable','off');
                set(app.BUBBLESORTButton,'Enable','off');
                set(app.INSERTIONSORTButton,'Enable','off');
                set(app.RANDOMARRAYButton,'Enable','off');
                set(app.CREATEARRAYButton,'Enable','off');
                
                radix_sort(app,app.unsorted_array)
                
                
                set(app.COUNTINGSORTButton,'Enable','on');
                set(app.BUCKETSORTButton,'Enable','on');
                set(app.RADIXSORTButton,'Enable','on');
                set(app.HEAPSORTButton,'Enable','on');
                set(app.QUICKSORTButton,'Enable','on');
                set(app.MERGESORTButton,'Enable','on');
                set(app.BUBBLESORTButton,'Enable','on');
                set(app.INSERTIONSORTButton,'Enable','on');
                set(app.RANDOMARRAYButton,'Enable','on');
                set(app.CREATEARRAYButton,'Enable','on');
           
            end
            
        end

        % Button pushed function: CREATEARRAYButton
        function CREATEARRAYButtonPushed(app, event)
                  try
                   value = app.EditField.Value;
                   app.unsorted_array = [];
                   temp=erase(value,{'[',']'});
                   value=split(temp,{' ',','});
                 
                       app.unsorted_array=str2double(value);
                       bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                       title(app.UIAxes2,'Sort Algorithm Animation');
                       for i=1:numel(app.unsorted_array)
                            text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                              'HorizontalAlignment','center',...
                                              'VerticalAlignment','bottom')
                       end
                  catch
                      msgbox('Please, check it array', 'Error','error');
               
                  end
             
               
        end

        % Button pushed function: FinishSortingButton
        function FinishSortingButtonPushed(app, event)
             cla(app.UIAxes2)
             app.unsorted_array = sort(app.unsorted_array);
             hold(app.UIAxes2,"on")
             bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2)
             hold(app.UIAxes2,"off")
             for i=1:numel(app.unsorted_array) 
                 text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
             end
               set(app.COUNTINGSORTButton,'Enable','on');
               set(app.BUCKETSORTButton,'Enable','on');
               set(app.RADIXSORTButton,'Enable','on');
               set(app.HEAPSORTButton,'Enable','on');
               set(app.QUICKSORTButton,'Enable','on');
               set(app.MERGESORTButton,'Enable','on');
               set(app.BUBBLESORTButton,'Enable','on');
               set(app.INSERTIONSORTButton,'Enable','on');
               set(app.RANDOMARRAYButton,'Enable','on');
               set(app.CREATEARRAYButton,'Enable','on');
             while true 
                 pause(1.)
             end
             
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
          cla(app.UIAxes2)
          app.array_size.Value = 2;
          app.lower_bound.Value = 0;
          app.upper_bound.Value = 0;
          app.EditField.Value = '';
          app.unsorted_array = [];
          
         
        end

        % Button pushed function: FindNumberButton
        function FindNumberButtonPushed(app, event)
        value = app.nthFibonacciValueEditField.Value;
        fibonacci(1) = 1; %First Fibonacci number is equal to 1.
        fibonacci(2) = 1; %Second Fibonacci number is equal to 1.
        for i = 3:value
            fibonacci(i) = fibonacci(i-1)+fibonacci(i-2); %%to go by adding the elements together.
        end
        answer = fibonacci(value);
        app.fibonacci_answer.Value = num2str(answer);
        bar(app.UIAxes3,fibonacci,'FaceColor',"[0.64 0.08 0.18]","EdgeColor","black",'LineWidth',2)
        title(app.UIAxes3,'Fibonacci Series');
        for i=1:numel(fibonacci)
                          text(app.UIAxes3,(i),fibonacci(i),num2str(fibonacci(i),'%0.0f'),...
                       'HorizontalAlignment','center',...
                       'VerticalAlignment','bottom')
        end

        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.UIAxes3);
            app.fibonacci_answer.Value=num2str(0);
            app.nthFibonacciValueEditField.Value=str2double("2");
        end

        % Button pushed function: CreateRandomArrayButton
        function CreateRandomArrayButtonPushed(app, event)
             try
                value1 = app.Array_size.Value;
                value2 = app.Upper_Bound.Value;
                value3 = app.Lower_Bound.Value;
                if (value2 == 0 && value3 == 0) || value1 == 0 %%error for not input
                    msgbox('Please check your array.','Error','error')
                else
                    if value3 >= value2 
                        msgbox('Should be upper range greater than lower range..','Error','error')
                    else
                            if (abs(value2-value3)<value1)
                                msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                            else
                                app.unsorted_array=[]; %%empty array list
                                while 1
                                    app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)]; %%create use randi for random array
                                    app.unsorted_array = unique(app.unsorted_array);
                                    
                                    if length(app.unsorted_array) == value1
                                        break
                                    end
                                end
                                app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array))); %%create random array for not see same numbers
                                app.UNSORTEDARRAYSTextArea.Value = num2str(app.unsorted_array); %%unsorted array
                                app.SORTEDARRAYSTextArea.Value = num2str(sort(app.unsorted_array)); %%unsorted array 
                            end
                    end
     
                end            
            catch
                msgbox('Please, check it array', 'Error','error');
            end
            
           
        end

        % Callback function
        function SORTARRAYButtonPushed(app, event)
           
            
        end

        % Button pushed function: FindNumberIndexButton
        function FindNumberIndexButtonPushed(app, event)
            try
            
                app.unsorted_array = sort(app.unsorted_array);
                find_number = str2double(app.KEYEditField.Value); 
                binary = binarySearch(app,app.unsorted_array,find_number,0); %%call binary func.
                app.NthIndexEditField.Value = num2str(binary); %%print index

            catch
                msgbox('Wrong Number!', 'Error','error');
                
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.UNSORTEDARRAYSTextArea.Value=('');
            app.SORTEDARRAYSTextArea.Value=('');

  
            app.KEYEditField.Value=('');
            app.NthIndexEditField.Value=('');
        end

        % Button pushed function: RETURNMAINPAGEButton, 
        % RETURNMAINPAGEButton_2, RETURNMAINPAGEButton_3, 
        % RETURNMAINPAGEButton_4, RETURNMAINPAGEButton_6, 
        % RETURNMAINPAGEButton_7, RETURNMAINPAGEButton_8
        function RETURNMAINPAGEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
        end

        % Button pushed function: RETURNMAINPAGEButton_5
        function RETURNMAINPAGEButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            global row
            global col
            global col2
            global row2
            global matrix1
            global matrix2
            global matrix3
            matrix1 = app.UITable.Data;
            matrix2 = app.UITable2.Data;
            if isempty(matrix1) && isempty(matrix2)
                 msgbox('Please check your matrix','Error','error')
            else
                if col ~= row2 
                msgbox('For matrix multiplication, the row of matrix one must be equal to the column of the matrix two','Error','error')
                else
         
                    [row , col] = size(matrix1); %%matrix1 size determination
                    [row2, col2] = size(matrix2); %%matrix2 size determination
                    matrix3 = zeros(row,col2);
                    for i = 1:row 
                        for j = 1:col2 
                            for k = 1:col 
                                matrix3(i,j) = matrix3(i,j) + (matrix1(i,k)* matrix2(k,j)); %%Create the result matrix
                                
                            end
                        end
                    end
                    app.UITable3.Data = matrix3; 
                end
         
               
            end


        end

        % Button pushed function: RANDOMARRAYButton
        function RANDOMARRAYButtonPushed(app, event)
            try
                 same = app.Switch.Value;
                 value1 = app.array_size.Value;
                 value2 = app.upper_bound.Value;
                 value3 = app.lower_bound.Value;
                if app.SeedCheckBox == 1
                   
                    if (value2 == 0 && value3 == 0) || value1 == 0
                        msgbox('Please check your array.','Error','error')
                    else
                        if value3 >= value2 
                            msgbox('Should be upper range greater than lower range..','Error','error')
                        else
                            if strcmp(same,'Not see same numbers')
                                if (abs(value2-value3)<value1)
                                    msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)];
                                        app.unsorted_array = unique(app.unsorted_array);
                                        
                                        if length(app.unsorted_array) == value1
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                 app.unsorted_array=randi([value3 value2],1,value1);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.64,0.08,0.18]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Animation');
                            for i=1:numel(app.unsorted_array)
                                      text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                      'HorizontalAlignment','center',...
                                      'VerticalAlignment','bottom')
                            end
                        end
                    end
                else
                    rng('default');
                    rng(1721);
                    if (value2 == 0 && value3 == 0) || value1 == 0
                        msgbox('Please check your array.','Error','error')
                    else
                        if value3 >= value2 
                            msgbox('Should be upper range greater than lower range..','Error','error')
                        else
                            if strcmp(same,'Not see same numbers')
                                if (abs(value2-value3)<value1)
                                    msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                                else
                                    app.unsorted_array=[];
                                    while 1
                                        app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)];
                                        app.unsorted_array = unique(app.unsorted_array);
                                        
                                        if length(app.unsorted_array) == value1
                                            break
                                        end
                                    end
                                    app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array)));
                                end
                            else
                                 app.unsorted_array=randi([value3 value2],1,value1);
                            end
                            bar(app.UIAxes2,app.unsorted_array,'FaceColor',"[0.4940 0.1840 0.5560]",'EdgeColor',"[0.8500 0.3250 0.0980]",'LineWidth',2);
                            title(app.UIAxes2,'Sort Algorithm Animation');
                            for i=1:numel(app.unsorted_array)
                                      text(app.UIAxes2,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                      'HorizontalAlignment','center',...
                                      'VerticalAlignment','bottom')
                            end
                        end
                    end
                end
                
         
            catch
                msgbox('Please, check it array', 'Error','error');
            end
            
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('https://www.youtube.com/channel/UCVx9odXaosySGaDtXX96QGA/', '-browser') 
        end

        % Button pushed function: HelpAboutSortingAlgorithmsButton
        function HelpAboutSortingAlgorithmsButtonPushed(app, event)
            inf_sorting;
        end

        % Button pushed function: AboutBinarySearchButton
        function AboutBinarySearchButtonPushed(app, event)
            inf_binary;
        end

        % Button pushed function: AboutFibonacciButton
        function AboutFibonacciButtonPushed(app, event)
            inf_fib;
        end

        % Button pushed function: ithsmallestButton
        function ithsmallestButtonPushed(app, event)
            try
            unsorted_arrays = sort(app.unsorted_array);
            x_axis = 1:length(unsorted_arrays);
            k = app.EditField_8.Value;
            results = qselect(app,app.unsorted_array,k);
            app.TextArea_5.Value = num2str(results);
            bar(app.UIAxes6,x_axis,unsorted_arrays,"white");
            hold(app.UIAxes6,"on")
            bar(app.UIAxes6, x_axis(k),unsorted_arrays(k),'FaceColor',"black",'EdgeColor',"red",'LineWidth',2);
            hold(app.UIAxes6,"off")
            for i=1:numel(unsorted_arrays) 
                title(app.UIAxes6,'Sorted Array');
                text(app.UIAxes6,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
            end
                          
            catch
                 msgbox('Check your index or array....', 'Error','error');
                 bar(app.UIAxes6,x_axis,unsorted_arrays,"black");
                 for i=1:numel(unsorted_arrays) 
                 title(app.UIAxes6,'Sorted Array');
                 text(app.UIAxes6,(i),unsorted_arrays(i),num2str(unsorted_arrays(i),'%0.0f'),...
                               'HorizontalAlignment','center',...
                                'VerticalAlignment','bottom')
                 end
                 
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
             %%clear button%%
            app.TextArea_4.Value = '';
            app.TextArea_5.Value = '';
            app.TextArea_6.Value = '';
            app.array_size_16.Value = 0;
            app.upper_bound_6.Value = 0;
            app.lower_bound_7.Value = 0;
            app.EditField_8.Value = 1;
            cla(app.UIAxes5)
            cla(app.UIAxes6)
            app.unsorted_array = [];
            
        end

        % Button pushed function: RandomArrayButton
        function RandomArrayButtonPushed(app, event)
             try
               same = app.IdonotwantseesamenumbersCheckBox.Value; %checkbox value
                value1 = app.array_size_16.Value;
                value2 = app.upper_bound_6.Value;
                value3 = app.lower_bound_7.Value;
                if (value2 == 0 && value3 == 0) || value1 == 0  %%%error for not input
                    msgbox('Please check your array.','Error','error')
                else
                    if value3 >= value2 
                        msgbox('Should be upper range greater than lower range..','Error','error')
                    else
                        if same 
                            if (abs(value2-value3)<value1)
                                msgbox('To obtain a unique array, the difference between the lower and upper bound must be greater than the length of the array...','Error','error')
                            else
                                app.unsorted_array=[];
                                while 1
                                    app.unsorted_array=[app.unsorted_array randi([value3 value2],1,1)]; %%different random array
                                    app.unsorted_array = unique(app.unsorted_array); %%unique function
                                    
                                    if length(app.unsorted_array) == value1
                                        break
                                    end
                                end
                                app.unsorted_array = app.unsorted_array(randperm(length(app.unsorted_array))); %%different random array and not same number
                                app.TextArea_4.Value = num2str(app.unsorted_array);
                                app.TextArea_6.Value = num2str(sort(app.unsorted_array));
                            end
                        else
                             app.unsorted_array=randi([value3 value2],1,value1); %%different random array
                             app.TextArea_4.Value = num2str(app.unsorted_array);
                             app.TextArea_6.Value = num2str(sort(app.unsorted_array));
                        end
                          bar(app.UIAxes5,app.unsorted_array,'FaceColor',"white",'EdgeColor',"black",'LineWidth',2);
                          for i=1:numel(app.unsorted_array) 
                          title(app.UIAxes5,'Unsorted Array');
                          text(app.UIAxes5,(i),app.unsorted_array(i),num2str(app.unsorted_array(i),'%0.0f'),...
                                       'HorizontalAlignment','center',...
                                        'VerticalAlignment','bottom')
                          end
                    end
                end
            catch
                msgbox('Please, check it array', 'Error','error');
             end
        end

        % Button pushed function: RandomizedSelectButton
        function RandomizedSelectButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(9);
        end

        % Button pushed function: AboutRandomizedSelectButton
        function AboutRandomizedSelectButtonPushed(app, event)
            inf_qs;
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
            app.HeapSortCheckBox.Value=0;
            app.BubbleSortCheckBox.Value=0;
            app.InsertionSortCheckBox.Value=0;
            app.MergeSortCheckBox.Value=0;
            app.QuickSortCheckBox.Value=0;
            app.BucketSortCheckBox.Value=0;
            app.RadixSortCheckBox.Value=0;
            app.CountingSortCheckBox.Value=0;
            cla(app.UIAxes);
        end

        % Button pushed function: ComparisonChoosenAlgorithmsButton
        function ComparisonChoosenAlgorithmsButtonPushed(app, event)
            if app.HeapSortCheckBox.Value==0 && app.BubbleSortCheckBox.Value==0 && ...
                    app.InsertionSortCheckBox.Value==0 && app.MergeSortCheckBox.Value==0 && ...
                    app.QuickSortCheckBox.Value==0 && app.BucketSortCheckBox.Value==0 && ...
                    app.RadixSortCheckBox.Value==0 && app.CountingSortCheckBox.Value==0
                msgbox('Please check the algorithms you want to compare...', 'Error','error');
            else
                checked_methods=[0 0 0 0 0 0 0 0];
                bar(app.UIAxes,[],[]);
                range_of_array = app.RangeEditField.Value;
                number_of_runs = app.ArraySizeEditField.Value;
                iteration_number = app.ArrayNumberEditField.Value;
                if app.InsertionSortCheckBox.Value
                    checked_methods(1)=1;
                else
                    checked_methods(1)=0;
                end
                if app.BubbleSortCheckBox.Value
                    checked_methods(2)=1;
                else
                    checked_methods(2)=0;
                end
                if app.MergeSortCheckBox.Value
                    checked_methods(3)=1;
                else
                    checked_methods(3)=0;
                end
                if app.BucketSortCheckBox.Value
                    checked_methods(4)=1;
                else
                    checked_methods(4)=0;
                end
                if app.RadixSortCheckBox.Value
                    checked_methods(5)=1;
                else
                    checked_methods(5)=0;
                end
                if app.CountingSortCheckBox.Value
                    checked_methods(6)=1;
                else
                    checked_methods(6)=0;
                end
                if app.QuickSortCheckBox.Value
                    checked_methods(7)=1;
                else
                    checked_methods(7)=0;
                end
                if app.HeapSortCheckBox.Value
                    checked_methods(8)=1;
                else
                    checked_methods(8)=0;
                end
                Time_Comparison(app,checked_methods,range_of_array,number_of_runs,iteration_number)
            end
        end

        % Button pushed function: ComparisonAllButton
        function ComparisonAllButtonPushed(app, event)
            sorting_methods=[1 1 1 1 1 1 1 1];
            bar(app.UIAxes,[],[]);
            range_of_array = app.RangeEditField.Value;
            number_of_runs = app.ArraySizeEditField.Value;
            iteration_number = app.ArrayNumberEditField.Value;
            Time_Comparison(app,sorting_methods,range_of_array,number_of_runs,iteration_number)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.BATUHANBEELLabel = uilabel(app.MainTab);
            app.BATUHANBEELLabel.Text = 'BATUHAN BEEL ';
            app.SortAlgorithmsButton = uibutton(app.MainTab, 'push');
            app.SortAlgorithmsButton.ButtonPushedFcn = createCallbackFcn(app, @SortAlgorithmsButtonPushed, true);
            app.SortAlgorithmsButton.Text = 'Sort Algorithms';
            app.SortAlgorithmsTimeComparisonButton = uibutton(app.MainTab, 'push');
            app.SortAlgorithmsTimeComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @SortAlgorithmsTimeComparisonButtonPushed, true);
            app.SortAlgorithmsTimeComparisonButton.Text = 'Sort Algorithms Time Comparison';
            app.BinarySearchButton = uibutton(app.MainTab, 'push');
            app.BinarySearchButton.ButtonPushedFcn = createCallbackFcn(app, @BinarySearchButtonPushed, true);
            app.BinarySearchButton.Text = 'Binary Search';
            app.FibonacciButton = uibutton(app.MainTab, 'push');
            app.FibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @FibonacciButtonPushed, true);
            app.FibonacciButton.Text = 'Fibonacci';
            app.MatrixProductButton = uibutton(app.MainTab, 'push');
            app.MatrixProductButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixProductButtonPushed, true);
            app.MatrixProductButton.Text = 'Matrix Product';
            app.ContactButton = uibutton(app.MainTab, 'push');
            app.ContactButton.ButtonPushedFcn = createCallbackFcn(app, @ContactButtonPushed, true);
            app.ContactButton.Text = 'Contact';
            app.IkcuLogoImage_2 = uiimage(app.MainTab);
            app.IkcuLogoImage_2.ImageClickedFcn = createCallbackFcn(app, @IkcuLogoImageClicked, true);
            app.IkcuLogoImage_2.ImageSource = 'Logo.gif';
            app.RandomizedSelectButton = uibutton(app.MainTab, 'push');
            app.RandomizedSelectButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizedSelectButtonPushed, true);
            app.RandomizedSelectButton.Text = 'Randomized Select';
            app.SortAlgorithmsTab = uitab(app.TabGroup);
            app.SortAlgorithmsTab.Title = 'Sort Algorithms';
            app.INSERTIONSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.INSERTIONSORTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTIONSORTButtonPushed, true);
            app.INSERTIONSORTButton.Text = 'INSERTION SORT';
            app.BUBBLESORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.BUBBLESORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUBBLESORTButtonPushed, true);
            app.BUBBLESORTButton.Text = 'BUBBLE SORT';
            app.MERGESORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.MERGESORTButton.ButtonPushedFcn = createCallbackFcn(app, @MERGESORTButtonPushed, true);
            app.MERGESORTButton.Text = 'MERGE SORT';
            app.QUICKSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.QUICKSORTButton.ButtonPushedFcn = createCallbackFcn(app, @QUICKSORTButtonPushed, true);
            app.QUICKSORTButton.Text = 'QUICK SORT';
            app.HEAPSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.HEAPSORTButton.ButtonPushedFcn = createCallbackFcn(app, @HEAPSORTButtonPushed, true);
            app.HEAPSORTButton.Text = 'HEAP SORT';
            app.RADIXSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.RADIXSORTButton.ButtonPushedFcn = createCallbackFcn(app, @RADIXSORTButtonPushed, true);
            app.RADIXSORTButton.Text = 'RADIX SORT';
            app.COUNTINGSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.COUNTINGSORTButton.ButtonPushedFcn = createCallbackFcn(app, @COUNTINGSORTButtonPushed, true);
            app.COUNTINGSORTButton.Text = 'COUNTING SORT';
            app.BUCKETSORTButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.BUCKETSORTButton.ButtonPushedFcn = createCallbackFcn(app, @BUCKETSORTButtonPushed, true);
            app.BUCKETSORTButton.Text = 'BUCKET SORT';
            app.UIAxes2 = uiaxes(app.SortAlgorithmsTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.lower_bound = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.lower_bound.Limits = [0 Inf];
            app.RANDOMARRAYButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.RANDOMARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RANDOMARRAYButtonPushed, true);
            app.RANDOMARRAYButton.Text = 'Random Array';
            app.LOWERBOUNDLabel = uilabel(app.SortAlgorithmsTab);
            app.LOWERBOUNDLabel.Text = 'LOWER BOUND';
            app.UPPERBOUNDLabel = uilabel(app.SortAlgorithmsTab);
            app.UPPERBOUNDLabel.Text = 'UPPER BOUND';
            app.upper_bound = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.upper_bound.Limits = [0 Inf];
            app.EditField = uieditfield(app.SortAlgorithmsTab, 'text');
            app.CREATEARRAYButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.CREATEARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEARRAYButtonPushed, true);
            app.CREATEARRAYButton.Text = 'CREATE ARRAY';
            app.PleasecreateanarrayonlyintegerLabel = uilabel(app.SortAlgorithmsTab);
            app.PleasecreateanarrayonlyintegerLabel.Text = 'Please create an array(only integer)';
            app.CLEARButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.FinishSortingButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.FinishSortingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSortingButtonPushed, true);
            app.FinishSortingButton.Text = 'Finish Sorting';
            app.Switch = uiswitch(app.SortAlgorithmsTab, 'slider');
            app.Switch.Items = {'Not contain same numbers', 'Contain same numbers'};
            app.Switch.Value = 'Not contain same numbers';
            app.array_size = uieditfield(app.SortAlgorithmsTab, 'numeric');
            app.array_size.Limits = [2 Inf];
            app.array_size.Value = 2;
            app.ARRAYSIZELabel = uilabel(app.SortAlgorithmsTab);
            app.ARRAYSIZELabel.Text = 'ARRAY SIZE';
            app.SortingSPEEDSliderLabel = uilabel(app.SortAlgorithmsTab);
            app.SortingSPEEDSliderLabel.Text = 'Sorting SPEED';
            app.SPEEDSlider = uislider(app.SortAlgorithmsTab);
            app.SPEEDSlider.Limits = [0 10];
            app.SPEEDSlider.MajorTicks = [];
            app.RETURNMAINPAGEButton_7 = uibutton(app.SortAlgorithmsTab, 'push');
            app.RETURNMAINPAGEButton_7.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_7.Text = 'RETURN MAIN PAGE';
            app.SeedCheckBox = uicheckbox(app.SortAlgorithmsTab);
            app.SeedCheckBox.Text = 'Seed ';
            app.HelpAboutSortingAlgorithmsButton = uibutton(app.SortAlgorithmsTab, 'push');
            app.HelpAboutSortingAlgorithmsButton.ButtonPushedFcn = createCallbackFcn(app, @HelpAboutSortingAlgorithmsButtonPushed, true);
            app.HelpAboutSortingAlgorithmsButton.Text = 'Help About Sorting Algorithms';
            app.SortAlgorithmsTimeComparisonTab = uitab(app.TabGroup);
            app.SortAlgorithmsTimeComparisonTab.Title = 'Sort Algorithms Time Comparison';
            app.RETURNMAINPAGEButton_6 = uibutton(app.SortAlgorithmsTimeComparisonTab, 'push');
            app.RETURNMAINPAGEButton_6.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_6.Text = 'RETURN MAIN PAGE';
            app.UIAxes = uiaxes(app.SortAlgorithmsTimeComparisonTab);
            title(app.UIAxes, {'Time Comparison'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BubbleSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.BubbleSortCheckBox.Text = 'Bubble Sort';
            app.InsertionSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.InsertionSortCheckBox.Text = 'Insertion Sort';
            app.MergeSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.MergeSortCheckBox.Text = 'Merge Sort';
            app.HeapSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.HeapSortCheckBox.Text = 'Heap Sort';
            app.QuickSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.QuickSortCheckBox.Text = 'Quick Sort';
            app.BucketSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.BucketSortCheckBox.Text = 'Bucket Sort';
            app.CountingSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.CountingSortCheckBox.Text = 'Counting Sort';
            app.RadixSortCheckBox = uicheckbox(app.SortAlgorithmsTimeComparisonTab);
            app.RadixSortCheckBox.Text = 'Radix Sort';
            app.ComparisonAllButton = uibutton(app.SortAlgorithmsTimeComparisonTab, 'push');
            app.ComparisonAllButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonAllButtonPushed, true);
            app.ComparisonAllButton.Text = 'Comparison All';
            app.ComparisonChoosenAlgorithmsButton = uibutton(app.SortAlgorithmsTimeComparisonTab, 'push');
            app.ComparisonChoosenAlgorithmsButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonChoosenAlgorithmsButtonPushed, true);
            app.ComparisonChoosenAlgorithmsButton.Text = 'Comparison Choosen Algorithms';
            app.ClearButton_3 = uibutton(app.SortAlgorithmsTimeComparisonTab, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.RangeEditField = uieditfield(app.SortAlgorithmsTimeComparisonTab, 'numeric');
            app.RangeEditField.Value = 1000;
            app.ArraySizeEditField = uieditfield(app.SortAlgorithmsTimeComparisonTab, 'numeric');
            app.ArraySizeEditField.Limits = [1 Inf];
            app.ArraySizeEditField.Value = 10;
            app.MaxRangeEditFieldLabel = uilabel(app.SortAlgorithmsTimeComparisonTab);
            app.MaxRangeEditFieldLabel.Text = 'Range';
            app.ArraySzieEditFieldLabel = uilabel(app.SortAlgorithmsTimeComparisonTab);
            app.ArraySzieEditFieldLabel.Text = 'Array Size';
            app.ArrayNumberEditFieldLabel = uilabel(app.SortAlgorithmsTimeComparisonTab);
            app.ArrayNumberEditFieldLabel.Visible = 'off';
            app.ArrayNumberEditFieldLabel.Text = 'Array Number';
            app.ArrayNumberEditField = uieditfield(app.SortAlgorithmsTimeComparisonTab, 'numeric');
            app.ArrayNumberEditField.Limits = [1 Inf];
            app.ArrayNumberEditField.Visible = 'off';
            app.ArrayNumberEditField.Value = 50;
            app.BinarySearchTab = uitab(app.TabGroup);
            app.BinarySearchTab.Title = 'Binary Search';
            app.CreateRandomArrayButton = uibutton(app.BinarySearchTab, 'push');
            app.CreateRandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRandomArrayButtonPushed, true);
            app.CreateRandomArrayButton.Text = 'Create Random Array';
            app.BINARYSEARCHLabel_2 = uilabel(app.BinarySearchTab);
            app.BINARYSEARCHLabel_2.Text = 'BINARY SEARCH';
            app.Lower_Bound = uieditfield(app.BinarySearchTab, 'numeric');
            app.Upper_Bound = uieditfield(app.BinarySearchTab, 'numeric');
            app.Array_size = uieditfield(app.BinarySearchTab, 'numeric');
            app.Array_size.Limits = [2 Inf];
            app.Array_size.Value = 2;
            app.ArraySizeLabel = uilabel(app.BinarySearchTab);
            app.ArraySizeLabel.Text = 'Array Size';
            app.UpperBoundLabel = uilabel(app.BinarySearchTab);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.LowerBoundLabel = uilabel(app.BinarySearchTab);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.ResetButton = uibutton(app.BinarySearchTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UNSORTEDARRAYSTextArea = uitextarea(app.BinarySearchTab);
            app.UNSORTEDARRAYSTextArea.Editable = 'off';
            app.SORTEDARRAYSTextArea = uitextarea(app.BinarySearchTab);
            app.SORTEDARRAYSTextArea.Editable = 'off';
            app.FindNumberIndexButton = uibutton(app.BinarySearchTab, 'push');
            app.FindNumberIndexButton.ButtonPushedFcn = createCallbackFcn(app, @FindNumberIndexButtonPushed, true);
            app.FindNumberIndexButton.Text = 'Find Number Index';
            app.KEYEditFieldLabel = uilabel(app.BinarySearchTab);
            app.KEYEditFieldLabel.Text = 'Key';
            app.KEYEditField = uieditfield(app.BinarySearchTab, 'text');
            app.NthIndexEditFieldLabel = uilabel(app.BinarySearchTab);
            app.NthIndexEditFieldLabel.Text = 'Nth. Index';
            app.NthIndexEditField = uieditfield(app.BinarySearchTab, 'text');
            app.RETURNMAINPAGEButton_5 = uibutton(app.BinarySearchTab, 'push');
            app.RETURNMAINPAGEButton_5.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButton_5Pushed, true);
            app.RETURNMAINPAGEButton_5.Text = 'RETURN MAIN PAGE';
            app.AboutBinarySearchButton = uibutton(app.BinarySearchTab, 'push');
            app.AboutBinarySearchButton.ButtonPushedFcn = createCallbackFcn(app, @AboutBinarySearchButtonPushed, true);
            app.AboutBinarySearchButton.Text = 'About Binary Search';
            app.FibonacciTab = uitab(app.TabGroup);
            app.FibonacciTab.Title = 'Fibonacci';
            app.UIAxes3 = uiaxes(app.FibonacciTab);
            title(app.UIAxes3, 'FIBONACCI SEQUENCE')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.FindNumberButton = uibutton(app.FibonacciTab, 'push');
            app.FindNumberButton.ButtonPushedFcn = createCallbackFcn(app, @FindNumberButtonPushed, true);
            app.FindNumberButton.Text = 'Find Number';
            app.ClearGraphButton = uibutton(app.FibonacciTab, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.nthFibonacciNumberEditFieldLabel = uilabel(app.FibonacciTab);
            app.nthFibonacciNumberEditFieldLabel.Text = 'n''th Fibonacci  Number';
            app.FibonacciValueEditFieldLabel = uilabel(app.FibonacciTab);
            app.FibonacciValueEditFieldLabel.Text = 'Fibonacci Value';
            app.nthFibonacciValueEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.nthFibonacciValueEditField.Limits = [2 Inf];
            app.nthFibonacciValueEditField.Value = 2;
            app.fibonacci_answer = uieditfield(app.FibonacciTab, 'text');
            app.RETURNMAINPAGEButton_4 = uibutton(app.FibonacciTab, 'push');
            app.RETURNMAINPAGEButton_4.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_4.Text = 'RETURN MAIN PAGE';
            app.AboutFibonacciButton = uibutton(app.FibonacciTab, 'push');
            app.AboutFibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @AboutFibonacciButtonPushed, true);
            app.AboutFibonacciButton.Text = 'About Fibonacci';
            app.MatrixProductTab = uitab(app.TabGroup);
            app.MatrixProductTab.Title = 'Matrix Product';
            app.UITable = uitable(app.MatrixProductTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable2 = uitable(app.MatrixProductTab);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable3 = uitable(app.MatrixProductTab);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.ClearButton = uibutton(app.MatrixProductTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FIRSTMATRIXLabel = uilabel(app.MatrixProductTab);
            app.FIRSTMATRIXLabel.Text = 'FIRST MATRIX ';
            app.SECONDMATRIXLabel = uilabel(app.MatrixProductTab);
            app.SECONDMATRIXLabel.Text = 'SECOND MATRIX ';
            app.CreateRandomMatrixButton = uibutton(app.MatrixProductTab, 'push');
            app.CreateRandomMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRandomMatrixButtonPushed, true);
            app.CreateRandomMatrixButton.Text = 'Create Random Matrix';
            app.RESULTMATRIXLabel = uilabel(app.MatrixProductTab);
            app.RESULTMATRIXLabel.Text = 'RESULT MATRIX ';
            app.MatrixProductLabel = uilabel(app.MatrixProductTab);
            app.MatrixProductLabel.Text = 'Matrix Product';
            app.CreateMatrixManuallyButton = uibutton(app.MatrixProductTab, 'push');
            app.CreateMatrixManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMatrixManuallyButtonPushed, true);
            app.CreateMatrixManuallyButton.Text = 'Create Matrix Manually';
            app.FirstMatrixRowLabel = uilabel(app.MatrixProductTab);
            app.FirstMatrixRowLabel.Text = 'First Matrix  Row ';
            app.row = uieditfield(app.MatrixProductTab, 'numeric');
            app.row.Limits = [1 Inf];
            app.row.Value = 1;
            app.FirstMatrixColumnLabel = uilabel(app.MatrixProductTab);
            app.FirstMatrixColumnLabel.Text = {'First Matrix Column  '; ''; ''; ''; ' '};
            app.col1androw2 = uieditfield(app.MatrixProductTab, 'numeric');
            app.col1androw2.Limits = [1 Inf];
            app.col1androw2.Value = 1;
            app.SecondMatrixRowEditFieldLabel = uilabel(app.MatrixProductTab);
            app.SecondMatrixRowEditFieldLabel.Text = 'Second Matrix Row    ';
            app.SecondMatrixRowEditField = uieditfield(app.MatrixProductTab, 'numeric');
            app.SecondMatrixRowEditField.Limits = [1 Inf];
            app.SecondMatrixRowEditField.Value = 1;
            app.SecondMatrixColumnLabel = uilabel(app.MatrixProductTab);
            app.SecondMatrixColumnLabel.Text = 'Second Matrix Column ';
            app.col2 = uieditfield(app.MatrixProductTab, 'numeric');
            app.col2.Limits = [1 Inf];
            app.col2.Value = 1;
            app.RETURNMAINPAGEButton_2 = uibutton(app.MatrixProductTab, 'push');
            app.RETURNMAINPAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_2.Text = 'RETURN MAIN PAGE';
            app.CalculateButton = uibutton(app.MatrixProductTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.IkcuLogoImage_4 = uiimage(app.MatrixProductTab);
            app.IkcuLogoImage_4.ImageClickedFcn = createCallbackFcn(app, @IkcuLogoImageClicked, true);
            app.IkcuLogoImage_4.ImageSource = 'Logo.gif';
            app.ContactTab = uitab(app.TabGroup);
            app.ContactTab.Title = 'Contact';
            app.GmailImage = uiimage(app.ContactTab);
            app.GmailImage.ImageClickedFcn = createCallbackFcn(app, @GmailImageClicked, true);
            app.GmailImage.ImageSource = 'Gmail-1.jpg';
            app.RETURNMAINPAGEButton_3 = uibutton(app.ContactTab, 'push');
            app.RETURNMAINPAGEButton_3.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_3.Text = 'RETURN MAIN PAGE';
            app.Label = uilabel(app.ContactTab);
            app.Label.Text = {'You can contact me for your questions.'; 'E-mail address : batuhan-498@hotmail.com'; 'Telephone number : +90 543 550 4483'; ''};
            app.Image2 = uiimage(app.ContactTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'youtubeLogo.png';
            app.IkcuLogoImage_3 = uiimage(app.ContactTab);
            app.IkcuLogoImage_3.ImageClickedFcn = createCallbackFcn(app, @IkcuLogoImageClicked, true);
            app.IkcuLogoImage_3.ImageSource = 'Logo.gif';
            app.BatuhanBEELsInformationLabel = uilabel(app.ContactTab);
            app.BatuhanBEELsInformationLabel.Text = 'Batuhan BEEL''s Information';
            app.Image3 = uiimage(app.ContactTab);
            app.Image3.ImageSource = 'information QRCODE.png';
            app.zmirKatipelebiWebLabel = uilabel(app.ContactTab);
            app.zmirKatipelebiWebLabel.Text = 'İzmir Katip Çelebi Web';
            app.Image3_2 = uiimage(app.ContactTab);
            app.Image3_2.ImageSource = 'İkçü QRCODE.png';
            app.MailTab = uitab(app.TabGroup);
            app.MailTab.Title = 'Mail';
            app.RETURNMAINPAGEButton = uibutton(app.MailTab, 'push');
            app.RETURNMAINPAGEButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton.Text = 'RETURN MAIN PAGE';
            app.SendMailButton = uibutton(app.MailTab, 'push');
            app.SendMailButton.ButtonPushedFcn = createCallbackFcn(app, @SendMailButtonPushed, true);
            app.SendMailButton.Text = 'Send';
            app.SUBJECTEditFieldLabel = uilabel(app.MailTab);
            app.SUBJECTEditFieldLabel.Text = 'SUBJECT:';
            app.SUBJECTEditField = uieditfield(app.MailTab, 'text');
            app.BODYTextAreaLabel = uilabel(app.MailTab);
            app.BODYTextAreaLabel.Text = 'BODY:';
            app.BODYTextArea = uitextarea(app.MailTab);
            app.MailsentsuccessfullyLabel = uilabel(app.MailTab);
            app.MailsentsuccessfullyLabel.Visible = 'off';
            app.MailsentsuccessfullyLabel.Text = 'Mail sent successfully.';
            app.IkcuLogoImage = uiimage(app.MailTab);
            app.IkcuLogoImage.ImageClickedFcn = createCallbackFcn(app, @IkcuLogoImageClicked, true);
            app.IkcuLogoImage.ImageSource = 'Logo.gif';
            app.RandomizedSelectTab = uitab(app.TabGroup);
            app.RandomizedSelectTab.Title = 'Randomized Select';
            app.UIAxes6 = uiaxes(app.RandomizedSelectTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes5 = uiaxes(app.RandomizedSelectTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.TextArea_4 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_4.Editable = 'off';
            app.RandomArrayButton = uibutton(app.RandomizedSelectTab, 'push');
            app.RandomArrayButton.ButtonPushedFcn = createCallbackFcn(app, @RandomArrayButtonPushed, true);
            app.RandomArrayButton.Text = 'Random Array';
            app.ithsmallestButton = uibutton(app.RandomizedSelectTab, 'push');
            app.ithsmallestButton.ButtonPushedFcn = createCallbackFcn(app, @ithsmallestButtonPushed, true);
            app.ithsmallestButton.Text = 'ith smallest';
            app.TextArea_5 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_5.Editable = 'off';
            app.upper_bound_6 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.lower_bound_7 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.EditField_8 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.EditField_8.Value = 1;
            app.ClearButton_2 = uibutton(app.RandomizedSelectTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.UNSORTEDARRAYLabel = uilabel(app.RandomizedSelectTab);
            app.UNSORTEDARRAYLabel.Text = 'UNSORTED ARRAY';
            app.INDEXLabel = uilabel(app.RandomizedSelectTab);
            app.INDEXLabel.Text = 'INDEX';
            app.NUMBERLabel = uilabel(app.RandomizedSelectTab);
            app.NUMBERLabel.Text = 'NUMBER';
            app.RANDOMIZEDSELECTLabel = uilabel(app.RandomizedSelectTab);
            app.RANDOMIZEDSELECTLabel.Text = 'RANDOMIZED SELECT';
            app.SORTEDARRAYLabel = uilabel(app.RandomizedSelectTab);
            app.SORTEDARRAYLabel.Text = 'SORTED ARRAY';
            app.array_size_16 = uieditfield(app.RandomizedSelectTab, 'numeric');
            app.ARRAYSIZELabel_2 = uilabel(app.RandomizedSelectTab);
            app.ARRAYSIZELabel_2.Text = 'ARRAY SIZE';
            app.UPPERBOUNDLabel_2 = uilabel(app.RandomizedSelectTab);
            app.UPPERBOUNDLabel_2.Text = 'UPPER BOUND';
            app.LOWERBOUNDLabel_2 = uilabel(app.RandomizedSelectTab);
            app.LOWERBOUNDLabel_2.Text = 'LOWER BOUND';
            app.IdonotwantseesamenumbersCheckBox = uicheckbox(app.RandomizedSelectTab);
            app.IdonotwantseesamenumbersCheckBox.Text = 'I do not want see same numbers';
            app.AboutRandomizedSelectButton = uibutton(app.RandomizedSelectTab, 'push');
            app.AboutRandomizedSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AboutRandomizedSelectButtonPushed, true);
            app.AboutRandomizedSelectButton.Text = 'About Randomized Select';
            app.TextArea_6 = uitextarea(app.RandomizedSelectTab);
            app.TextArea_6.Editable = 'off';
            app.RETURNMAINPAGEButton_8 = uibutton(app.RandomizedSelectTab, 'push');
            app.RETURNMAINPAGEButton_8.ButtonPushedFcn = createCallbackFcn(app, @RETURNMAINPAGEButtonPushed, true);
            app.RETURNMAINPAGEButton_8.Text = 'RETURN MAIN PAGE';
            app.IkcuLogoImage_5 = uiimage(app.RandomizedSelectTab);
            app.IkcuLogoImage_5.ImageClickedFcn = createCallbackFcn(app, @IkcuLogoImageClicked, true);
            app.IkcuLogoImage_5.ImageSource = 'Logo.gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end